classdef PCA_plotter_2d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataExplorer2DUIFigure     matlab.ui.Figure
        PopupasMatlabfigButton     matlab.ui.control.Button
        ClearPlotButton            matlab.ui.control.Button
        ShowLegendCheckBox         matlab.ui.control.CheckBox
        AddColorButton             matlab.ui.control.Button
        AddDataButton              matlab.ui.control.Button
        PlotYOnlyButton            matlab.ui.control.Button
        SelectedColorListBox       matlab.ui.control.ListBox
        SelectedColorListBoxLabel  matlab.ui.control.Label
        RemoveLastColorButton      matlab.ui.control.Button
        ColorListBox               matlab.ui.control.ListBox
        ColorListBoxLabel          matlab.ui.control.Label
        TitleEditField             matlab.ui.control.EditField
        TitleEditFieldLabel        matlab.ui.control.Label
        YlabelEditField            matlab.ui.control.EditField
        YlabelEditFieldLabel       matlab.ui.control.Label
        XlabelEditField            matlab.ui.control.EditField
        XlabelEditFieldLabel       matlab.ui.control.Label
        SelecttheDropDowntoloadthedataLabel  matlab.ui.control.Label
        YLoadStatusEditField       matlab.ui.control.EditField
        YLoadStatusEditFieldLabel  matlab.ui.control.Label
        XLoadStatusEditField       matlab.ui.control.EditField
        XLoadStatusEditFieldLabel  matlab.ui.control.Label
        LoadDataButton             matlab.ui.control.Button
        RemoveDataButton           matlab.ui.control.Button
        SelectedListBox            matlab.ui.control.ListBox
        SelectedListBoxLabel       matlab.ui.control.Label
        LoadFolderButton           matlab.ui.control.Button
        FilefolderEditField        matlab.ui.control.EditField
        FilefolderEditFieldLabel   matlab.ui.control.Label
        ExportFigureButton         matlab.ui.control.Button
        YaxisDropDown              matlab.ui.control.DropDown
        YaxisDropDownLabel         matlab.ui.control.Label
        XaxisDropDown              matlab.ui.control.DropDown
        XaxisDropDownLabel         matlab.ui.control.Label
        ClearAllButton             matlab.ui.control.Button
        AddLinePlotButton          matlab.ui.control.Button
        DataFrameListBox           matlab.ui.control.ListBox
        DataFrameListBoxLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties
        %Property % Description
        filoc %file folder location
        listing
        selectedlist = {}
        xtab
        ytab
        bigtable = {}
        xData = []
        yData = []
        color1 = [0, 0.4470, 0.7410] %Blue
        color2 = [0.8500, 0.3250, 0.0980] %Red
        color3 = [0.9290, 0.6940, 0.1250] %Yellow
        color4 = [0.4940, 0.1840, 0.5560] %Purple
        color5 = [0.3010, 0.7450, 0.9330] %Pale Blue
        color6 = [0.4660, 0.6740, 0.1880] %Green
        selectedcolor = {}
        groupcolor = {}
        textsize = 18
        legend_handle = {}
        labeledlegend = {}
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FilefolderEditField
        function FilefolderEditFieldValueChanged(app, event)
            app.filoc = app.FilefolderEditField.Value;

        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            try
                folderhandle = dir(app.filoc);
                app.listing = {folderhandle.name};
                filnum = length(app.listing);
                app.DataFrameListBox.Items = {app.listing{3:filnum}};
            catch
            end
            %app.DataFrameListBox.Items
        end

        % Value changed function: DataFrameListBox
        function DataFrameListBoxValueChanged(app, event)
            %             value = app.DataFrameListBox.Value;
            %             %value
            %             app.selectedlist(end+1) = {value};
            %             %app.selectedlist
            %             app.SelectedListBox.Items = app.selectedlist;
        end

        % Button pushed function: RemoveDataButton
        function RemoveDataButtonPushed(app, event)
            if length(app.SelectedListBox.Items)>0
                app.selectedlist(end) = [];
                app.SelectedListBox.Items(end) = [];
                app.DataFrameListBox.Value = app.DataFrameListBox.Items(1);
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            try
                filnam = app.SelectedListBox.Items;
                for iter = 1:length(filnam)
                    T = readtable([app.filoc '/' filnam{iter}]);
                    if iter == 1
                        app.ytab = T.Properties.VariableNames;
                        app.xtab = app.ytab;
                    end
                    app.bigtable(end+1) = {T};
                end
                app.XaxisDropDown.Items = app.xtab;
                app.YaxisDropDown.Items = app.ytab;

                %Initialization: Load the first column of all tables to xData/yData
                tablecolumn = 1;

                for iter = 1:length(filnam)
                    smalltable = app.bigtable{iter};
                    smalltabledata = smalltable.Variables;
                    app.xData(:,iter) = smalltabledata(:,tablecolumn);
                    app.yData(:,iter) = smalltabledata(:,tablecolumn);
                end
                %             size(app.xData)
                %             size(app.yData)
                if isempty(app.xData)
                    app.XLoadStatusEditField.Value = 'No Data!';
                    app.YLoadStatusEditField.Value = 'No Data!';
                else
                    app.XLoadStatusEditField.Value = 'Loaded!';
                    app.YLoadStatusEditField.Value = 'Loaded!';
                    app.UIAxes.XLabel.String = app.xtab{1};
                    app.UIAxes.YLabel.String = app.xtab{1};
                end
            catch
            end
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            value = app.XaxisDropDown.Value;
            logic = strcmp(app.xtab,value);
            tablecolumn = find(logic,1); %col 1 to col 9

            filnam = app.SelectedListBox.Items;
            for iter = 1:length(filnam)
                smalltable = app.bigtable{iter};
                smalltabledata = smalltable.Variables;
                app.xData(:,iter) = smalltabledata(:,tablecolumn);
            end
            %app.xData
            app.UIAxes.XLabel.String = value;

        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownValueChanged(app, event)
            value = app.YaxisDropDown.Value;
            logic = strcmp(app.ytab,value);
            tablecolumn = find(logic,1);
            filnam = app.SelectedListBox.Items;
            for iter = 1:length(filnam)
                smalltable = app.bigtable{iter};
                smalltabledata = smalltable.Variables;
                app.yData(:,iter) = smalltabledata(:,tablecolumn);
            end
            %app.yData
            app.UIAxes.YLabel.String = value;
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            value = app.TitleEditField.Value;
            app.UIAxes.Title.String = value;
        end

        % Value changed function: XlabelEditField
        function XlabelEditFieldValueChanged(app, event)
            value = app.XlabelEditField.Value;
            app.UIAxes.XLabel.String = value;
        end

        % Value changed function: YlabelEditField
        function YlabelEditFieldValueChanged(app, event)
            value = app.YlabelEditField.Value;
            app.UIAxes.YLabel.String = value;
        end

        % Value changed function: ColorListBox
        function ColorListBoxValueChanged(app, event)
            %             value = app.ColorListBox.Value;
            %             %value
            %             app.selectedcolor(end+1) = {value};
            %             app.SelectedColorListBox.Items = app.selectedcolor;
            %             colornum = length(app.SelectedColorListBox.Items);
            %
            %             for iter=1:colornum
            %                 if strcmp(app.selectedcolor(iter),'Blue') == 1
            %                     app.groupcolor(iter) = {app.color1};
            %                 elseif strcmp(app.selectedcolor(iter),'Red') == 1
            %                     app.groupcolor(iter) = {app.color2};
            %                 elseif strcmp(app.selectedcolor(iter),'Yellow') == 1
            %                     app.groupcolor(iter) = {app.color3};
            %                 elseif strcmp(app.selectedcolor(iter),'Purple') == 1
            %                     app.groupcolor(iter) = {app.color4};
            %                 elseif strcmp(app.selectedcolor(iter),'Pale Blue') == 1
            %                     app.groupcolor(iter) = {app.color5};
            %                 elseif strcmp(app.selectedcolor(iter),'Green') == 1
            %                     app.groupcolor(iter) = {app.color6};
            %                 end
            %             end

            %app.groupcolor{1}

        end

        % Button pushed function: RemoveLastColorButton
        function RemoveLastColorButtonPushed(app, event)
            if length(app.SelectedColorListBox.Items)>0
                app.selectedcolor(end) = [];
                app.SelectedColorListBox.Items(end) = [];
                app.ColorListBox.Value = app.ColorListBox.Items(1);
            end
        end

        % Button pushed function: AddLinePlotButton
        function AddLinePlotButtonPushed(app, event)
            try
                filnum = length(app.SelectedListBox.Items);
                number_of_plots = 1:1:filnum;
                label = {};
                %plot(app.UIAxes,app.xData(:,1),app.yData(:,1),'Color',app.groupcolor{1});
                for iter = 1:filnum
                    plot(app.UIAxes,app.xData(:,iter),app.yData(:,iter),'Color',app.groupcolor{iter});
                    %hold(app.UIAxes)
                    text(app.UIAxes,app.xData(end,iter),app.yData(end,iter),...
                         ['o' num2str(number_of_plots(iter))],...
                        'FontSize',app.textsize,...
                        'HorizontalAlignment',"center",...
                        "FontWeight","bold");
                end
                for iter = 1:filnum
                    datanam = split(app.SelectedListBox.Items{iter},'.');
                    label{iter} = [num2str(iter) '. ' datanam{1}];
                end
                app.legend_handle = legend(app.UIAxes,label,'Location','eastoutside');

%                 for iter = 1:filnum
%                     scatter(app.UIAxes,app.xData(end,iter),app.yData(end,iter),14,...
%                         'MarkerEdgeColor','k',...
%                         'MarkerFaceColor','k');
%                 end

                if app.ShowLegendCheckBox.Value == 1
                    set(app.legend_handle,'visible','on')
                else
                    set(app.legend_handle,'visible','off')
                end
            catch
            end

        end

        % Button pushed function: AddDataButton
        function AddDataButtonPushed(app, event)
            value = app.DataFrameListBox.Value;
            if isempty(value)
            else
                %value
                app.selectedlist(end+1) = {value};
                %app.selectedlist
                app.SelectedListBox.Items = app.selectedlist;
            end
        end

        % Button pushed function: AddColorButton
        function AddColorButtonPushed(app, event)
            value = app.ColorListBox.Value;
            %value
            if isempty(value)
            else
                app.selectedcolor(end+1) = {value};
                app.SelectedColorListBox.Items = app.selectedcolor;
                colornum = length(app.SelectedColorListBox.Items);

                for iter=1:colornum
                    if strcmp(app.selectedcolor(iter),'Blue') == 1
                        app.groupcolor(iter) = {app.color1};
                    elseif strcmp(app.selectedcolor(iter),'Red') == 1
                        app.groupcolor(iter) = {app.color2};
                    elseif strcmp(app.selectedcolor(iter),'Yellow') == 1
                        app.groupcolor(iter) = {app.color3};
                    elseif strcmp(app.selectedcolor(iter),'Purple') == 1
                        app.groupcolor(iter) = {app.color4};
                    elseif strcmp(app.selectedcolor(iter),'Pale Blue') == 1
                        app.groupcolor(iter) = {app.color5};
                    elseif strcmp(app.selectedcolor(iter),'Green') == 1
                        app.groupcolor(iter) = {app.color6};
                    end
                end
            end
        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
            value = app.ShowLegendCheckBox.Value;
            if value == 1
                set(app.legend_handle,'visible','on')
            else
                set(app.legend_handle,'visible','off')
            end
        end

        % Button pushed function: PlotYOnlyButton
        function PlotYOnlyButtonPushed(app, event)
            try
                filnum = length(app.SelectedListBox.Items);
                number_of_plots = 1:1:filnum;
                label={};
                row = 1:1:length(app.yData);
                %plot(app.UIAxes,app.xData(:,1),app.yData(:,1),'Color',app.groupcolor{1});
                for iter = 1:filnum
                    plot(app.UIAxes,row,app.yData(:,iter),'Color',app.groupcolor{iter});
                    %hold(app.UIAxes)
                    text(app.UIAxes,row(end),app.yData(end,iter),...
                        num2str(number_of_plots(iter)),...
                        'FontSize',app.textsize);
                end
                for iter = 1:filnum
                    datanam = split(app.SelectedListBox.Items{iter},'.');
                    label{iter} = [num2str(iter) '. ' datanam{1}];
                end
                app.legend_handle = legend(app.UIAxes,label,'Location','eastoutside');
                app.UIAxes.XLabel.String= 'Row number';
                if app.ShowLegendCheckBox.Value == 1
                    set(app.legend_handle,'visible','on')
                else
                    set(app.legend_handle,'visible','off')
                end
            catch
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            cla(app.UIAxes)
            %set(app.legend_handle,'visible','off')
            app.SelectedListBox.Items = {};
            app.SelectedColorListBox.Items = {};
            app.selectedlist = {};
            app.selectedcolor = {};
            app.xData = [];
            app.yData = [];
            app.XLoadStatusEditField.Value = 'Not Loaded!';
            app.YLoadStatusEditField.Value = 'Not Loaded!';


        end

        % Button pushed function: ExportFigureButton
        function ExportFigureButtonPushed(app, event)
            if isempty(app.UIAxes)

            else
                filter = {'*.jpg';'*.png';'*.tif';'*.pdf';'*.eps'};
                [filename,filepath] = uiputfile(filter);
                if ischar(filename)
                    exportgraphics(app.UIAxes,[filepath filename]);
                end
            end
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes)
            %set(app.legend_handle,'visible','off')

            %Reinitialize
            filnam = app.SelectedListBox.Items;
            tablecolumn = 1;
            for iter = 1:length(filnam)
                smalltable = app.bigtable{iter};
                smalltabledata = smalltable.Variables;
                app.xData(:,iter) = smalltabledata(:,tablecolumn);
                app.yData(:,iter) = smalltabledata(:,tablecolumn);
            end
            %             size(app.xData)
            %             size(app.yData)
            if isempty(app.xData)
                app.XLoadStatusEditField.Value = 'No Data!';
                app.YLoadStatusEditField.Value = 'No Data!';
            else
                app.XaxisDropDown.Value = app.XaxisDropDown.Items{1};
                app.YaxisDropDown.Value = app.YaxisDropDown.Items{1};
                app.XLoadStatusEditField.Value = 'Loaded!';
                app.YLoadStatusEditField.Value = 'Loaded!';
                app.UIAxes.XLabel.String = app.xtab{1};
                app.UIAxes.YLabel.String = app.xtab{1};
            end
        end

        % Button pushed function: PopupasMatlabfigButton
        function PopupasMatlabfigButtonPushed(app, event)
            if isempty(app.UIAxes)

            else
                fig = figure;
                fig.Visible = 'on';
                figAxes = axes(fig);
                % Copy all UIAxes children, take over axes limits and aspect ratio.
                allChildren = app.UIAxes.XAxis.Parent.Children;
                copyobj(allChildren, figAxes)
                figAxes.XLim = app.UIAxes.XLim;
                figAxes.YLim = app.UIAxes.YLim;
                figAxes.ZLim = app.UIAxes.ZLim;
                figAxes.XGrid = app.UIAxes.XGrid;
                figAxes.YGrid = app.UIAxes.YGrid;
                figAxes.XMinorGrid = app.UIAxes.XMinorGrid;
                figAxes.YMinorGrid = app.UIAxes.YMinorGrid;
                figAxes.SortMethod = app.UIAxes.SortMethod;
                figAxes.NextPlot = app.UIAxes.NextPlot;
                figAxes.Box = app.UIAxes.Box;
                figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
                lgndName1 = app.UIAxes.Legend.String;
                lgd = legend(lgndName1);
                lgd.Box = app.UIAxes.Legend.Box;
                lgd.Location = app.UIAxes.Legend.Location;
                figAxes.YLabel.String = app.UIAxes.YLabel.String;
                figAxes.YLabel.FontSize = app.UIAxes.YLabel.FontSize;
                figAxes.XLabel.String = app.UIAxes.XLabel.String;
                figAxes.XLabel.FontSize = app.UIAxes.XLabel.FontSize;
                figAxes.Title.String = app.UIAxes.Title.String;
                figAxes.Title.FontSize = app.UIAxes.Title.FontSize;


                % Save as png and fig files.
                %[filename,filepath] = uiputfile('*.fig');
                %savefig(fig, filename);
                %savefig([app.filoc '\' app.UIAxes.Title.String '.fig'])
                % Delete the temporary figure.
                %delete(fig);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataExplorer2DUIFigure = uifigure('Visible', 'off');
            app.DataExplorer2DUIFigure.Name = 'DataExplorer2D';
            app.UIAxes = uiaxes(app.DataExplorer2DUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Xlabel')
            ylabel(app.UIAxes, 'Ylabel')
            app.DataFrameListBoxLabel = uilabel(app.DataExplorer2DUIFigure);
            app.DataFrameListBoxLabel.Text = 'Data Frame';
            app.DataFrameListBox = uilistbox(app.DataExplorer2DUIFigure);
            app.DataFrameListBox.Items = {};
            app.DataFrameListBox.ValueChangedFcn = createCallbackFcn(app, @DataFrameListBoxValueChanged, true);
            app.DataFrameListBox.Value = {};
            app.AddLinePlotButton = uibutton(app.DataExplorer2DUIFigure, 'push');
            app.AddLinePlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddLinePlotButtonPushed, true);
            app.AddLinePlotButton.Text = 'Add Line Plot';
            app.ClearAllButton = uibutton(app.DataExplorer2DUIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.XaxisDropDownLabel = uilabel(app.DataExplorer2DUIFigure);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.DataExplorer2DUIFigure);
            app.XaxisDropDown.Items = {};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = {};
            app.YaxisDropDownLabel = uilabel(app.DataExplorer2DUIFigure);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.DataExplorer2DUIFigure);
            app.YaxisDropDown.Items = {};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.YaxisDropDown.Value = {};
            app.ExportFigureButton = uibutton(app.DataExplorer2DUIFigure, 'push');
            app.ExportFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureButtonPushed, true);
            app.ExportFigureButton.Text = 'Export Figure';
            app.FilefolderEditFieldLabel = uilabel(app.DataExplorer2DUIFigure);
            app.FilefolderEditFieldLabel.Text = 'File folder';
            app.FilefolderEditField = uieditfield(app.DataExplorer2DUIFigure, 'text');
            app.FilefolderEditField.ValueChangedFcn = createCallbackFcn(app, @FilefolderEditFieldValueChanged, true);
            app.LoadFolderButton = uibutton(app.DataExplorer2DUIFigure, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.SelectedListBoxLabel = uilabel(app.DataExplorer2DUIFigure);
            app.SelectedListBoxLabel.Text = 'Selected';
            app.SelectedListBox = uilistbox(app.DataExplorer2DUIFigure);
            app.SelectedListBox.Items = {};
            app.SelectedListBox.Value = {};
            app.RemoveDataButton = uibutton(app.DataExplorer2DUIFigure, 'push');
            app.RemoveDataButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDataButtonPushed, true);
            app.RemoveDataButton.Text = 'Remove Data';
            app.LoadDataButton = uibutton(app.DataExplorer2DUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.XLoadStatusEditFieldLabel = uilabel(app.DataExplorer2DUIFigure);
            app.XLoadStatusEditFieldLabel.Text = 'X Load Status';
            app.XLoadStatusEditField = uieditfield(app.DataExplorer2DUIFigure, 'text');
            app.XLoadStatusEditField.Value = 'Not loaded!';
            app.YLoadStatusEditFieldLabel = uilabel(app.DataExplorer2DUIFigure);
            app.YLoadStatusEditFieldLabel.Text = 'Y Load Status';
            app.YLoadStatusEditField = uieditfield(app.DataExplorer2DUIFigure, 'text');
            app.YLoadStatusEditField.Value = 'Not loaded!';
            app.SelecttheDropDowntoloadthedataLabel = uilabel(app.DataExplorer2DUIFigure);
            app.SelecttheDropDowntoloadthedataLabel.Text = 'Select the Drop Down to load the data.';
            app.XlabelEditFieldLabel = uilabel(app.DataExplorer2DUIFigure);
            app.XlabelEditFieldLabel.Text = 'Xlabel';
            app.XlabelEditField = uieditfield(app.DataExplorer2DUIFigure, 'text');
            app.XlabelEditField.ValueChangedFcn = createCallbackFcn(app, @XlabelEditFieldValueChanged, true);
            app.YlabelEditFieldLabel = uilabel(app.DataExplorer2DUIFigure);
            app.YlabelEditFieldLabel.Text = 'Ylabel';
            app.YlabelEditField = uieditfield(app.DataExplorer2DUIFigure, 'text');
            app.YlabelEditField.ValueChangedFcn = createCallbackFcn(app, @YlabelEditFieldValueChanged, true);
            app.TitleEditFieldLabel = uilabel(app.DataExplorer2DUIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.DataExplorer2DUIFigure, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.ColorListBoxLabel = uilabel(app.DataExplorer2DUIFigure);
            app.ColorListBoxLabel.Text = 'Color';
            app.ColorListBox = uilistbox(app.DataExplorer2DUIFigure);
            app.ColorListBox.Items = {'Blue', 'Red', 'Yellow', 'Purple', 'Pale Blue', 'Green'};
            app.ColorListBox.ValueChangedFcn = createCallbackFcn(app, @ColorListBoxValueChanged, true);
            app.ColorListBox.Value = {};
            app.RemoveLastColorButton = uibutton(app.DataExplorer2DUIFigure, 'push');
            app.RemoveLastColorButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLastColorButtonPushed, true);
            app.RemoveLastColorButton.Text = 'Remove Last Color';
            app.SelectedColorListBoxLabel = uilabel(app.DataExplorer2DUIFigure);
            app.SelectedColorListBoxLabel.Text = 'Selected Color';
            app.SelectedColorListBox = uilistbox(app.DataExplorer2DUIFigure);
            app.SelectedColorListBox.Items = {};
            app.SelectedColorListBox.Value = {};
            app.PlotYOnlyButton = uibutton(app.DataExplorer2DUIFigure, 'push');
            app.PlotYOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @PlotYOnlyButtonPushed, true);
            app.PlotYOnlyButton.Text = 'Plot Y Only';
            app.AddDataButton = uibutton(app.DataExplorer2DUIFigure, 'push');
            app.AddDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonPushed, true);
            app.AddDataButton.Text = 'Add Data';
            app.AddColorButton = uibutton(app.DataExplorer2DUIFigure, 'push');
            app.AddColorButton.ButtonPushedFcn = createCallbackFcn(app, @AddColorButtonPushed, true);
            app.AddColorButton.Text = 'Add Color';
            app.ShowLegendCheckBox = uicheckbox(app.DataExplorer2DUIFigure);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.ShowLegendCheckBox.Value = true;
            app.ClearPlotButton = uibutton(app.DataExplorer2DUIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.PopupasMatlabfigButton = uibutton(app.DataExplorer2DUIFigure, 'push');
            app.PopupasMatlabfigButton.ButtonPushedFcn = createCallbackFcn(app, @PopupasMatlabfigButtonPushed, true);
            app.PopupasMatlabfigButton.Text = 'Popup as Matlab fig';
            % Show the figure after all components are created
            app.DataExplorer2DUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCA_plotter_2d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataExplorer2DUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataExplorer2DUIFigure)
        end
    end
end